classdef AUV_FEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConsoleLog                     matlab.ui.control.TextArea
        PanelConsole                   matlab.ui.container.Panel
        PanelMesh                      matlab.ui.container.Panel
        RightOptions                   matlab.ui.container.Panel
        WaveAmplitude                  matlab.ui.control.NumericEditField
        ScaleLabel_7                   matlab.ui.control.Label
        IncidentThetaListEditField     matlab.ui.control.EditField
        IncidentListLabel              matlab.ui.control.Label
        PeriodListEditField            matlab.ui.control.EditField
        PeriodListEditFieldLabel       matlab.ui.control.Label
        ImportPhisButton               matlab.ui.control.Button
        WaveNumber                     matlab.ui.control.NumericEditField
        ScaleLabel_6                   matlab.ui.control.Label
        Wavelength                     matlab.ui.control.NumericEditField
        ScaleLabel_5                   matlab.ui.control.Label
        WaterDensity                   matlab.ui.control.NumericEditField
        ScaleLabel_4                   matlab.ui.control.Label
        Gravity                        matlab.ui.control.NumericEditField
        ScaleLabel_3                   matlab.ui.control.Label
        LeftOptions                    matlab.ui.container.Panel
        ShowMassCheckBox               matlab.ui.control.CheckBox
        ShowImportMeshCheckBox         matlab.ui.control.CheckBox
        ShowGeometryMeshCheckBox       matlab.ui.control.CheckBox
        WaterplaneFaceNumberEditField  matlab.ui.control.NumericEditField
        WaterplaneFaceNumberEditField_2Label  matlab.ui.control.Label
        ImportPointMassesButton        matlab.ui.control.Button
        ScaleMeshField                 matlab.ui.control.NumericEditField
        ScaleLabel_2                   matlab.ui.control.Label
        HminField                      matlab.ui.control.NumericEditField
        HminHmaxLabel                  matlab.ui.control.Label
        HmaxField                      matlab.ui.control.NumericEditField
        HminHmaxLabel_3                matlab.ui.control.Label
        ScaleGeometryField             matlab.ui.control.NumericEditField
        ScaleLabel                     matlab.ui.control.Label
        Normals6DCheckBox              matlab.ui.control.CheckBox
        NormalsCheckBox                matlab.ui.control.CheckBox
        ImportMeshButton               matlab.ui.control.Button
        MeshGeometryButton             matlab.ui.control.Button
        ViewGeometryButton             matlab.ui.control.Button
        ImportGeometryButton           matlab.ui.control.Button
        MeshPlot                       matlab.ui.control.UIAxes
        PanelRAO                       matlab.ui.container.Panel
        PanelRAOPlotOptions            matlab.ui.container.Panel
        OrLabel                        matlab.ui.control.Label
        DisplayLabel                   matlab.ui.control.Label
        HeaveSurgeSwayCheckBox         matlab.ui.control.CheckBox
        YawRollPitchCheckBox           matlab.ui.control.CheckBox
        ExportResponsesButton          matlab.ui.control.Button
        ExportPhisButton               matlab.ui.control.Button
        CalculateRAOsButton            matlab.ui.control.Button
        CalculatePhisButton            matlab.ui.control.Button
        PanelRAOPlot                   matlab.ui.container.Panel
    end

    properties (Access = private)
        GeometryFigureNumber % the number of handle of the geometry figure
        GeometryScale % value that scales geometry size
        HydrostaticStiffnessMatrix % matrix of hyrdrostatic restoring terms
        LastScaleGeometryValue % the previous value of ScaleGeometryField
        LastScaleMeshValue % the previous value of ScaleMeshField
        Mesh % mesh of submerged object
        MeshGeometry % mesh of submerged geometry object
        Model % model of submerged object 
        Periods % a list of periods to evaluate the RAO at
        PointMasses % a list of pointmasses (~,4) [m,x,y,z]
        RAOAxes % a list of plots in the TiledLayoutRAO object
        ResponseAOs % a list of RAOs
        Responses % a list of glider responses to waves
        ResponsePeriods % a list of response periods
        ResponseThetas % a list of response thetas
        Thetas % a list of incident wave angles
        TiledLayoutRAO % a tiled layout for plotting RAO
        TrimeshScuffedFigureNumber % trimesh opens a figure for no reason
        VelocityPotentials % a list of velocities potentials
        VelocityPotentialPeriods % a list of velocity potential periods
    end

    methods (Access = private)
        
        function [] = initPanelRAO(app)
            % Creates Tiled Layout in Panel
            app.TiledLayoutRAO = tiledlayout(app.PanelRAOPlot,3,1);
            app.TiledLayoutRAO.TileSpacing = 'compact';
            xlabel(app.TiledLayoutRAO,'Period');
            ylabel(app.TiledLayoutRAO,'RAO');

            % Creates Axes in Tiled Layout
            app.RAOAxes = zeros(3,1);
            app.RAOAxes(3) = nexttile(app.TiledLayoutRAO,1);
            app.RAOAxes(1) = nexttile(app.TiledLayoutRAO,2);
            app.RAOAxes(2) = nexttile(app.TiledLayoutRAO,3);
            updateRAOAxesTitles(app);
            linkaxes([app.RAOAxes(1),app.RAOAxes(2),app.RAOAxes(3)],'x');
        end
        
        function [] = log(app,text)
            app.ConsoleLog.Value = cellstr([app.ConsoleLog.Value(:)',text]);
            pause(0.00000001);
            uiprogressdlg(app.UIFigure);
            scroll(app.ConsoleLog, 'bottom');
        end

        function [] = updateGeometryPlot(app)
            % Only updates geometry when plot is open
            if (ishandle(app.GeometryFigureNumber))
                figure(app.GeometryFigureNumber);
                try
                    pdegplot(app.Model,"FaceLabels","on");
                catch error                
                    log(app,error.message);
                end
            end
        end

        function [] = updateMeshPlot(app)
            if app.ShowImportMeshCheckBox.Value == true
                mesh = app.Mesh;
            else
                mesh = app.MeshGeometry;
            end

            % Trimesh opens an empty figure before 'Parent' steals its
            %  draw focus. This opens the figure empty itself to then
            %  automatically close it afterward.
            figure(app.TrimeshScuffedFigureNumber);
            try
                trimesh(mesh,'Parent',app.MeshPlot);
            catch
                close(app.TrimeshScuffedFigureNumber);
                if app.ShowMassCheckBox.Value == true
                    try
                        updatePointMassesPlot(app);
                    catch
                    end
                end
                return;
            end
            close(app.TrimeshScuffedFigureNumber);

            hold(app.MeshPlot,"on");
            axis(app.MeshPlot,"equal");

            % Finds each triangle's center point and face normal vectors
            CP  = incenter(mesh);
            FN = -faceNormal(mesh);
            
            if app.NormalsCheckBox.Value == true
                % Plotes the triangulation object's normals
                quiver3(CP(:,1),CP(:,2),CP(:,3), ...
                     FN(:,1),FN(:,2),FN(:,3),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end

            if app.Normals6DCheckBox.Value == true
                % Creates each triangle's 6-dimensional normal vector
                FN6 = normal6DOF(CP,FN);
                
                % Plotes the triangulation object's rotational normals
                quiver3(app.MeshPlot,CP(:,1),CP(:,2),CP(:,3), ...
                     FN6(:,4),FN6(:,5),FN6(:,6),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end

            hold(app.MeshPlot,"off");

            if app.ShowMassCheckBox.Value == true
                try
                    updatePointMassesPlot(app);
                catch
                end
            end
        end

        function [] = updatePointMassesPlot(app)
            pm = app.PointMasses;
            [row,~] = size(pm);
            hold(app.MeshPlot,"on");
            for j = 1:row
                plot3(app.MeshPlot,pm(j,2),pm(j,3),pm(j,4),"*");
            end
            hold(app.MeshPlot,"off");
        end

        function [] = updateRAOPlot(app)
            T = app.ResponsePeriods;

            plotTypes = ["rx","bo","g*","bx","go","r*","gx","ro","b*"];

            if app.HeaveSurgeSwayCheckBox.Value == true
                offset = 0;
            else
                offset = 3;
            end

            [~,nT] = size(app.ResponsePeriods);
            [~,ntheta] = size(app.ResponseThetas);

            for k = 1:3
                plotID = app.RAOAxes(k);
                xlim(app.RAOAxes(1),[min(T) - 0.5,max(T) + 0.5])
                
                hold(plotID,"off");
                plot(plotID,T(1),app.ResponseAOs(k + offset,1,1),"x");
                hold(plotID,"on");

                for j = 1:nT
                    for i = 1:ntheta
                        if i ~= 1 || j ~= 1
                            plot(plotID,T(j),app.ResponseAOs(k + offset,i,j),plotTypes(i));
                        end
                    end
                end
                hold(plotID,"off");
            end  
            updateRAOAxesTitles(app);
        end
        
        function [] = updateRAOAxesTitles(app)
            if app.HeaveSurgeSwayCheckBox.Value == true
                title(app.RAOAxes(3),'Heave');
                title(app.RAOAxes(1),'Surge');
                title(app.RAOAxes(2),'Sway');
            else
                title(app.RAOAxes(3),'Yaw');
                title(app.RAOAxes(1),'Roll');
                title(app.RAOAxes(2),'Pitch');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            initPanelRAO(app);
            app.GeometryFigureNumber = 1;
            app.TrimeshScuffedFigureNumber = 2;
            app.LastScaleGeometryValue = app.ScaleGeometryField.Value;
            app.LastScaleMeshValue = app.ScaleMeshField.Value;
            axis(app.MeshPlot, "equal");
            app.WaveNumber.Value = 2*pi()/app.Wavelength.Value;
            app.Periods = str2num(app.PeriodListEditField.Value);
            app.YawRollPitchCheckBox.Value = ~app.HeaveSurgeSwayCheckBox.Value;
            app.ShowGeometryMeshCheckBox.Value = ~app.ShowImportMeshCheckBox.Value;
            app.ConsoleLog.Value = "Welcome to AUV_FEA_Solver 1.0";
            app.Thetas = str2num(app.IncidentThetaListEditField.Value);
            uiprogressdlg(app.UIFigure);
            app.VelocityPotentials = [];
            app.VelocityPotentialPeriods = [];
            app.ResponseThetas = [];
            app.ResponsePeriods = [];
        end

        % Button pushed function: ImportGeometryButton
        function ImportGeometryButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            figure(app.UIFigure); % requests focus back

            try  
                model = createpde();
                importGeometry(model,append(path,file));
                scale(model.Geometry, app.ScaleGeometryField.Value);
                %translate(model.Geometry, [0.5,0,0]);
                app.Model = model;

                log(app,append("Imported Geometry: ",file));

                figure(app.GeometryFigureNumber);
                updateGeometryPlot(app);
            catch error                
                log(app,error.message);
            end
        end

        % Button pushed function: ImportPointMassesButton
        function ImportPointMassesButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                pointMasses = readmatrix(append(path,file));

                [~,col] = size(pointMasses);
                if col == 4
                    pointMasses(:,2:4) = pointMasses(:,2:4);% + [0.5,0,0];
                    app.PointMasses = pointMasses;

                    updateMeshPlot(app);

                    log(app,append("Imported Point Masses: ",file));
                else
                    log(app,sprintf("Failed to import %s.\nIncorrect dimensions.",file));
                end
            catch error                
                log(app,error.message);
            end
        end

        % Button pushed function: ImportMeshButton
        function ImportMeshButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            figure(app.UIFigure); % requests focus back

            try
                mesh = stlread(append(path,file));
                mesh = triangulation(mesh.ConnectivityList, ...
                    mesh.Points*app.ScaleMeshField.Value); %+ [.5,0,0]);
                app.Mesh = mesh;

                updateMeshPlot(app);

                log(app,append("Imported Mesh: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Button pushed function: ImportPhisButton
        function ImportPhisButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                vpsm = readmatrix(append(path,file)); % imports velocity potentials
                
                N = vpsm(1,1); % first value list the length of velocity potentials
                nT = vpsm(1,2); % first value list the length of periods 

                velocityPotentials = zeros(6,N,nT);
                for j = 1:nT
                    for i = 1:6
                        velocityPotentials(i,:,j) = vpsm(6*j + i - 5,1:N);
                    end
                end

                app.VelocityPotentials = velocityPotentials;
                app.VelocityPotentialPeriods = vpsm(1,3:nT + 2);

                log(app,append("Imported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Button pushed function: ExportPhisButton
        function ExportPhisButtonPushed(app, event)
            [file,path] = uiputfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                [~,N,nT] = size(app.VelocityPotentials);

                export = zeros(nT*6 + 1,max(N,nT + 1));
                export(1,1) = N;
                export(1,2) = nT;
                export(1,3:nT + 2) = app.VelocityPotentialPeriods;
                for j = 1:nT
                    for i = 1:6
                        export(1 + (j - 1)*6 + i,1:N) = app.VelocityPotentials(i,:,j);
                    end
                end

                 writematrix(export, append(path,file));

                log(app,append("Exported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Button pushed function: ExportResponsesButton
        function ExportResponsesButtonPushed(app, event)
            [file,path] = uiputfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                [~,ntheta,nT] = size(app.ResponseAOs);

                export = zeros(ntheta*6 + 2,max(ntheta + 1,nT + 1));
                export(1,1) = ntheta;
                export(1,2:ntheta + 1) = app.ResponseThetas;
                export(2,1) = nT;
                export(2,2:nT + 1) = app.VelocityPotentialPeriods;
                for j = 1:ntheta
                    for i = 1:6
                        export(2 + (j - 1)*6 + i,1:nT) = app.Responses(i,j,:);
                    end
                end

                 writematrix(export, append(path,file));

                log(app,append("Exported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Button pushed function: CalculatePhisButton
        function CalculatePhisButtonPushed(app, event)
            T = setdiff(app.Periods,app.VelocityPotentialPeriods);
            g = app.Gravity.Value;
            mesh = app.Mesh;
            
            try
                % Finds each triangle's center point and face normal vectors
                CP  = incenter(mesh);
                FN = -faceNormal(mesh);
            catch
                log(app,"Add a mesh first.");
                return
            end
            
            [N,~] = size(CP);

            % Defines a list of N triangles so that parfor can nicely slice data
            Tri = zeros(3,3,N);
            for j = 1:N
                Tri(:,:,j) = mesh.Points(mesh.ConnectivityList(j,:),:);
            end

            [~,nT] = size(T);

            runseconds = N*N*0.00018*3*nT;
            runminutes = runseconds/60;
            log(app,sprintf("Estimated Runtime ~ %.0fs or %.1fmin",runseconds,runminutes));

            % Calculates veloctiy potentials for each period 
            phis = zeros(6,N,nT);
            for j = 1:nT
                log(app,sprintf("----%.0f%%----",(j - 1)/nT*100));
                
                K = (2*pi()/T(j))^2/g; % K = w^2/g

                phis(:,:,j) = velocityPotential(Tri,CP,FN,K);
            end
            log(app,"----Done----");

            % Combine all periods
            [~,nTOld] = size(app.VelocityPotentialPeriods);
            app.VelocityPotentialPeriods = [app.VelocityPotentialPeriods,T];

            % Combine all velocity potentials
            phisOld = app.VelocityPotentials;
            app.VelocityPotentials = zeros(6,N,nT+nTOld);
            app.VelocityPotentials(:,:,1:nTOld) = phisOld;
            app.VelocityPotentials(:,:,(nTOld + 1):(nTOld + nT)) = phis;
        end

        % Button pushed function: CalculateRAOsButton
        function CalculateRAOsButtonPushed(app, event)
            PM = app.PointMasses;
            g = app.Gravity.Value;
            p = app.WaterDensity.Value;
            %face = app.WaterplaneFaceNumberEditField.Value;
            T = app.VelocityPotentialPeriods;
            phis = app.VelocityPotentials;
            theta = app.Thetas;
            mesh = app.Mesh;
            a = app.WaveAmplitude.Value;
            k = app.WaveNumber.Value;
            
            try
                M = bodyInertiaMatrix(PM);
            catch
                log(app,"Must import point masses first!");
                return
            end
            
            try
                C = hydrostaticRestoringMatrix(PM,g,p,app.Model,mesh);
            catch error
                log(app,error.message);%"Must mesh geometry first!");
                return
            end

            try
                [N,~] = size(mesh.ConnectivityList);
            catch
                log(app,"No imported body mesh, using meshed geometry instead!");

                mesh = app.MeshGeometry;
                [N,~] = size(mesh.ConnectivityList);
            end

            CP = incenter(mesh);
            FN = -faceNormal(mesh);
            FN6 = normal6DOF(CP,FN);

            % Defines a list of N triangles so that parfor can nicely slice data
            Tri = zeros(3,3,N);
            for j = 1:N
                Tri(:,:,j) = mesh.Points(mesh.ConnectivityList(j,:),:);
            end

            [~,nT] = size(T);
            [~,ntheta] = size(theta);

            % Responses and response amplitude operators
            Hs = zeros(6,ntheta,nT);
            RAOs = zeros(6,ntheta,nT);

            % Calculates the responses and response amplitude operators
            for j = 1:nT
                log(app,sprintf("----%.0f%%----",(j - 1)/nT*100));

                w = 2*pi()/T(j);
                
                [D] = addedMassAndDampingMatrices(Tri,phis(:,:,j),FN6,p);

                figure(5)
                hold on
                plot(w,real(D(3,3)),"ro");
                figure(6)
                hold on
                plot(w,-imag(D(3,3)/w),"ro");


                for i = 1:ntheta
                    [F] = excitingForce(Tri,phis(:,:,j),FN,p,k,g,theta(i));

                    figure(7)
                    hold on
                    plot(w,abs(F(3)),"ro");
                
                    nu = linsolve(-w^2*(M + D) + C,F);
                
                    Hs(:,i,j) = nu / a; % response function
                    RAOs(:,i,j) = abs(Hs(:,i,j)); % response amplitude operator
                end
            end
            
            % Save all values
            app.ResponsePeriods = T;
            app.ResponseThetas = theta;
            app.Responses = Hs;
            app.ResponseAOs = RAOs;

            try
                updateRAOPlot(app);
                log(app,"----Done----");
            catch
                log(app,"Calculate velocity potentials first!");
            end
        end

        % Button pushed function: ViewGeometryButton
        function ViewGeometryButtonPushed(app, event)
            figure(app.GeometryFigureNumber);
            updateGeometryPlot(app);
        end

        % Button pushed function: MeshGeometryButton
        function MeshGeometryButtonPushed(app, event)
            try
                generateMesh(app.Model, 'GeometricOrder','linear','Hmin',app.HminField.Value,'Hmax',app.HmaxField.Value);
                
                % Makes a triangulation object of Model.Mesh's surface
                mto = triangulation(app.Model.Mesh.Elements.',app.Model.Mesh.Nodes.');                
                [T,P] = freeBoundary(mto);
                mesh = triangulation(T,P);
                app.MeshGeometry = mesh;

                updateMeshPlot(app);
                
                log(app,sprintf("Sucessfully meshed geometry.\nHmin: %f, Hmax: %f",app.HminField.Value,app.HmaxField.Value));
            catch error             
                log(app,error.message);
            end
        end

        % Value changed function: NormalsCheckBox
        function NormalsCheckBoxValueChanged(app, event)
            updateMeshPlot(app);   
        end

        % Value changed function: Normals6DCheckBox
        function Normals6DCheckBoxValueChanged(app, event)
                updateMeshPlot(app);
        end

        % Value changed function: PeriodListEditField
        function PeriodListEditFieldValueChanged(app, event)
            try
                app.Periods = str2num(app.PeriodListEditField.Value);
            catch error                
                log(app,error.message);
            end
        end

        % Value changed function: IncidentThetaListEditField
        function IncidentThetaListEditFieldValueChanged(app, event)
            try
                app.Thetas = str2num(app.IncidentThetaListEditField.Value);
            catch error                
                log(app,error.message);
            end
        end

        % Value changed function: ScaleGeometryField
        function ScaleGeometryFieldValueChanged(app, event)
            value = app.ScaleGeometryField.Value/app.LastScaleGeometryValue;
            try
                scale(app.Model.Geometry, value);
                app.LastScaleGeometryValue = app.ScaleGeometryField.Value;
                updateGeometryPlot(app);
            catch
            end
        end

        % Value changed function: ScaleMeshField
        function ScaleMeshFieldValueChanged(app, event)
            value = app.ScaleMeshField.Value/app.LastScaleMeshValue;
            try
                app.Mesh = triangulation(app.Mesh.ConnectivityList, ...
                    app.Mesh.Points*value);
                app.LastScaleMeshValue = app.ScaleMeshField.Value;
                updateMeshPlot(app);
            catch
            end
        end

        % Value changed function: Wavelength
        function WavelengthValueChanged(app, event)
            app.WaveNumber.Value = 2*pi()/app.Wavelength.Value;
        end

        % Value changed function: WaveNumber
        function WaveNumberValueChanged(app, event)
            app.Wavelength.Value = 2*pi()/app.WaveNumber.Value;
        end

        % Value changed function: HeaveSurgeSwayCheckBox
        function HeaveSurgeSwayCheckBoxValueChanged(app, event)
            app.YawRollPitchCheckBox.Value = ~app.HeaveSurgeSwayCheckBox.Value;
            try
                updateRAOPlot(app);
            catch
            end
        end

        % Value changed function: YawRollPitchCheckBox
        function YawRollPitchCheckBoxValueChanged(app, event)
            app.HeaveSurgeSwayCheckBox.Value = ~app.YawRollPitchCheckBox.Value;
            try
                updateRAOPlot(app);
            catch
            end
        end

        % Value changed function: ShowImportMeshCheckBox
        function ShowImportMeshCheckBoxValueChanged(app, event)
            app.ShowGeometryMeshCheckBox.Value = ~app.ShowImportMeshCheckBox.Value;       
            updateMeshPlot(app);
        end

        % Value changed function: ShowGeometryMeshCheckBox
        function ShowGeometryMeshCheckBoxValueChanged(app, event)
            app.ShowImportMeshCheckBox.Value = ~app.ShowGeometryMeshCheckBox.Value;
            updateMeshPlot(app);
        end

        % Value changed function: ShowMassCheckBox
        function ShowMassCheckBoxValueChanged(app, event)
            updateMeshPlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelRAO = uipanel(app.UIFigure);
            app.PanelRAOPlot = uipanel(app.PanelRAO);
            app.PanelRAOPlotOptions = uipanel(app.PanelRAO);
            app.PanelRAOPlotOptions.Title = 'RAO Panel';
            app.CalculatePhisButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.CalculatePhisButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePhisButtonPushed, true);
            app.CalculatePhisButton.Text = 'Calculate Φs';
            app.CalculateRAOsButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.CalculateRAOsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRAOsButtonPushed, true);
            app.CalculateRAOsButton.Text = 'Calculate RAOs';
            app.ExportPhisButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.ExportPhisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPhisButtonPushed, true);
            app.ExportPhisButton.Text = 'Export Φs';
            app.ExportResponsesButton = uibutton(app.PanelRAOPlotOptions, 'push');
            app.ExportResponsesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResponsesButtonPushed, true);
            app.ExportResponsesButton.Text = 'Export Responses';
            app.YawRollPitchCheckBox = uicheckbox(app.PanelRAOPlotOptions);
            app.YawRollPitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @YawRollPitchCheckBoxValueChanged, true);
            app.YawRollPitchCheckBox.Text = 'Yaw, Roll, Pitch';
            app.HeaveSurgeSwayCheckBox = uicheckbox(app.PanelRAOPlotOptions);
            app.HeaveSurgeSwayCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeaveSurgeSwayCheckBoxValueChanged, true);
            app.HeaveSurgeSwayCheckBox.Text = 'Heave, Surge, Sway';
            app.HeaveSurgeSwayCheckBox.Value = true;
            app.DisplayLabel = uilabel(app.PanelRAOPlotOptions);
            app.DisplayLabel.Text = 'Display';
            app.OrLabel = uilabel(app.PanelRAOPlotOptions);
            app.OrLabel.Text = 'Or';
            app.PanelMesh = uipanel(app.UIFigure);
            app.MeshPlot = uiaxes(app.PanelMesh);
            title(app.MeshPlot, 'Mesh Plot')
            xlabel(app.MeshPlot, 'X')
            ylabel(app.MeshPlot, 'Y')
            zlabel(app.MeshPlot, 'Z')
            app.MeshPlot.XLim = [-1 0.5];
            app.MeshPlot.YLim = [-0.5 0.5];
            app.MeshPlot.ZLim = [-0.5 0];
            app.LeftOptions = uipanel(app.PanelMesh);
            app.LeftOptions.Title = 'Mesh Options';
            app.ImportGeometryButton = uibutton(app.LeftOptions, 'push');
            app.ImportGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGeometryButtonPushed, true);
            app.ImportGeometryButton.Text = 'Import Geometry';
            app.ViewGeometryButton = uibutton(app.LeftOptions, 'push');
            app.ViewGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ViewGeometryButtonPushed, true);
            app.ViewGeometryButton.Text = 'View Geometry';
            app.MeshGeometryButton = uibutton(app.LeftOptions, 'push');
            app.MeshGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @MeshGeometryButtonPushed, true);
            app.MeshGeometryButton.Text = 'Mesh Geometry';
            app.ImportMeshButton = uibutton(app.LeftOptions, 'push');
            app.ImportMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMeshButtonPushed, true);
            app.ImportMeshButton.Text = 'Import Mesh';
            app.NormalsCheckBox = uicheckbox(app.LeftOptions);
            app.NormalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalsCheckBoxValueChanged, true);
            app.NormalsCheckBox.Text = 'Show Normals';
            app.Normals6DCheckBox = uicheckbox(app.LeftOptions);
            app.Normals6DCheckBox.ValueChangedFcn = createCallbackFcn(app, @Normals6DCheckBoxValueChanged, true);
            app.Normals6DCheckBox.Text = 'Show 6D Normals';
            app.ScaleLabel = uilabel(app.LeftOptions);
            app.ScaleLabel.Text = 'Scale Geo';
            app.ScaleGeometryField = uieditfield(app.LeftOptions, 'numeric');
            app.ScaleGeometryField.ValueChangedFcn = createCallbackFcn(app, @ScaleGeometryFieldValueChanged, true);
            app.ScaleGeometryField.Value = 0.001;
            app.HminHmaxLabel_3 = uilabel(app.LeftOptions);
            app.HminHmaxLabel_3.Text = 'Hmax';
            app.HmaxField = uieditfield(app.LeftOptions, 'numeric');
            app.HmaxField.Value = 0.1;
            app.HminHmaxLabel = uilabel(app.LeftOptions);
            app.HminHmaxLabel.Text = 'Hmin';
            app.HminField = uieditfield(app.LeftOptions, 'numeric');
            app.HminField.Value = 0.01;
            app.ScaleLabel_2 = uilabel(app.LeftOptions);
            app.ScaleLabel_2.Text = 'Scale Msh';
            app.ScaleMeshField = uieditfield(app.LeftOptions, 'numeric');
            app.ScaleMeshField.ValueChangedFcn = createCallbackFcn(app, @ScaleMeshFieldValueChanged, true);
            app.ScaleMeshField.Value = 10000;
            app.ImportPointMassesButton = uibutton(app.LeftOptions, 'push');
            app.ImportPointMassesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPointMassesButtonPushed, true);
            app.ImportPointMassesButton.Text = 'Import Point Masses';
            app.WaterplaneFaceNumberEditField_2Label = uilabel(app.LeftOptions);
            app.WaterplaneFaceNumberEditField_2Label.Text = 'Waterplane Face Number';
            app.WaterplaneFaceNumberEditField = uieditfield(app.LeftOptions, 'numeric');
            app.WaterplaneFaceNumberEditField.Value = 2;
            app.ShowGeometryMeshCheckBox = uicheckbox(app.LeftOptions);
            app.ShowGeometryMeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGeometryMeshCheckBoxValueChanged, true);
            app.ShowGeometryMeshCheckBox.Text = 'Show Geo Msh';
            app.ShowImportMeshCheckBox = uicheckbox(app.LeftOptions);
            app.ShowImportMeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowImportMeshCheckBoxValueChanged, true);
            app.ShowImportMeshCheckBox.Text = 'Show Import Msh';
            app.ShowImportMeshCheckBox.Value = true;
            app.ShowMassCheckBox = uicheckbox(app.LeftOptions);
            app.ShowMassCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMassCheckBoxValueChanged, true);
            app.ShowMassCheckBox.Text = 'Show Mass';
            app.ShowMassCheckBox.Value = true;
            app.RightOptions = uipanel(app.PanelMesh);
            app.RightOptions.Title = 'RAO Options';
            app.ScaleLabel_3 = uilabel(app.RightOptions);
            app.ScaleLabel_3.Text = 'Gravity';
            app.Gravity = uieditfield(app.RightOptions, 'numeric');
            app.Gravity.Value = 9.81;
            app.ScaleLabel_4 = uilabel(app.RightOptions);
            app.ScaleLabel_4.Text = 'Water Density';
            app.WaterDensity = uieditfield(app.RightOptions, 'numeric');
            app.WaterDensity.Value = 1026;
            app.ScaleLabel_5 = uilabel(app.RightOptions);
            app.ScaleLabel_5.Text = 'Wavelength';
            app.Wavelength = uieditfield(app.RightOptions, 'numeric');
            app.Wavelength.ValueChangedFcn = createCallbackFcn(app, @WavelengthValueChanged, true);
            app.Wavelength.Value = 106.4;
            app.ScaleLabel_6 = uilabel(app.RightOptions);
            app.ScaleLabel_6.Text = 'Wave Number';
            app.WaveNumber = uieditfield(app.RightOptions, 'numeric');
            app.WaveNumber.ValueChangedFcn = createCallbackFcn(app, @WaveNumberValueChanged, true);
            app.WaveNumber.Value = 0.059052;
            app.ImportPhisButton = uibutton(app.RightOptions, 'push');
            app.ImportPhisButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPhisButtonPushed, true);
            app.ImportPhisButton.Text = 'Import Φs';
            app.PeriodListEditFieldLabel = uilabel(app.RightOptions);
            app.PeriodListEditFieldLabel.Text = 'Period List';
            app.PeriodListEditField = uieditfield(app.RightOptions, 'text');
            app.PeriodListEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodListEditFieldValueChanged, true);
            app.PeriodListEditField.Value = '[0.5:0.5:10]';
            app.IncidentListLabel = uilabel(app.RightOptions);
            app.IncidentListLabel.Text = 'Incident θ List';
            app.IncidentThetaListEditField = uieditfield(app.RightOptions, 'text');
            app.IncidentThetaListEditField.ValueChangedFcn = createCallbackFcn(app, @IncidentThetaListEditFieldValueChanged, true);
            app.IncidentThetaListEditField.Value = '[0,pi/2]';
            app.ScaleLabel_7 = uilabel(app.RightOptions);
            app.ScaleLabel_7.Text = 'Wave Amplitude';
            app.WaveAmplitude = uieditfield(app.RightOptions, 'numeric');
            app.WaveAmplitude.Value = 1;
            app.PanelConsole = uipanel(app.UIFigure);
            app.ConsoleLog = uitextarea(app.UIFigure);
            app.ConsoleLog.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUV_FEA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end