classdef AUV_FEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PanelMesh                   matlab.ui.container.Panel
        RightOptions                matlab.ui.container.Panel
        DisplayLabel                matlab.ui.control.Label
        YawRollPitchCheckBox        matlab.ui.control.CheckBox
        OrLabel                     matlab.ui.control.Label
        HeaveSurgeSwayCheckBox      matlab.ui.control.CheckBox
        ExportResponseButton        matlab.ui.control.Button
        ExportPhisButton            matlab.ui.control.Button
        CalculateRAOsButton         matlab.ui.control.Button
        CalculatePhisButton         matlab.ui.control.Button
        ClearPhisButton             matlab.ui.control.Button
        PhisAreLoadedLabel          matlab.ui.control.Label
        PhisLamp                    matlab.ui.control.Lamp
        IncidentThetaListEditField  matlab.ui.control.EditField
        IncidentListLabel           matlab.ui.control.Label
        PeriodListEditField         matlab.ui.control.EditField
        PeriodListEditFieldLabel    matlab.ui.control.Label
        ImportPhisButton            matlab.ui.control.Button
        GravityEditField            matlab.ui.control.NumericEditField
        ScaleLabel_3                matlab.ui.control.Label
        LeftOptions                 matlab.ui.container.Panel
        MeshLamp                    matlab.ui.control.Lamp
        ShowSurfaceCheckBox         matlab.ui.control.CheckBox
        ShowBodyCheckBox            matlab.ui.control.CheckBox
        SaveButton                  matlab.ui.control.Button
        SetRollCheckBox             matlab.ui.control.CheckBox
        SetPitchCheckBox            matlab.ui.control.CheckBox
        LoadButton                  matlab.ui.control.Button
        SubmergeMeshButton          matlab.ui.control.Button
        WaterDensity                matlab.ui.control.NumericEditField
        ScaleLabel_4                matlab.ui.control.Label
        ShowMassCheckBox            matlab.ui.control.CheckBox
        ImportPointMassesButton     matlab.ui.control.Button
        HminField                   matlab.ui.control.NumericEditField
        HminHmaxLabel               matlab.ui.control.Label
        HmaxField                   matlab.ui.control.NumericEditField
        HminHmaxLabel_3             matlab.ui.control.Label
        ScaleField                  matlab.ui.control.NumericEditField
        ScaleLabel                  matlab.ui.control.Label
        RotationNormalsCheckBox     matlab.ui.control.CheckBox
        NormalsCheckBox             matlab.ui.control.CheckBox
        MeshButton                  matlab.ui.control.Button
        ImportModelButton           matlab.ui.control.Button
        MeshPlot                    matlab.ui.control.UIAxes
        PanelRAO                    matlab.ui.container.Panel
        PanelConsole                matlab.ui.container.Panel
        ConsoleLog                  matlab.ui.control.TextArea
        PanelRAOPlot                matlab.ui.container.Panel
    end

    properties (Access = private)
        ConsoleHeight % Constant height of the console
        EP % target maximum offset
        GeometryFigureNumber % the number of handle of the geometry figure
        LastScaleValue % the previous value of ScaleField
        MeshBody % mesh of whole object
        MeshBodySurface % mesh of body surface for plotting purposes
        MeshSubmergedSurface % mesh of submerged object
        Model % model of submerged object 
        ObjectPath % Path to the STL file last used
        ObjectFile % File name of the STL file last used
        Periods % a list of periods to evaluate the RAO at
        PointMasses % a list of pointmasses (~,4) [m,x,y,z]
        PointMassesOffset % Vector from original object to new position
        PointMassesAngleOffset % Vector from original object to new angle
        RAOAxes % a list of plots in the TiledLayoutRAO object
        ResponseAOs % a list of RAOs
        Responses % a list of glider responses to waves
        ResponsePeriods % a list of response periods
        ResponseThetas % a list of response thetas
        Thetas % a list of incident wave angles
        TiledLayoutRAO % a tiled layout for plotting RAO
        TrimeshScuffedFigureNumber % trimesh opens a figure for no reason
        VelocityPotentials % a list of velocities potentials
        VelocityPotentialPeriods % a list of velocity potential periods
    end

    methods (Access = private)
        
        function [] = initPanelRAO(app)
            % Creates Tiled Layout in Panel
            app.TiledLayoutRAO = tiledlayout(app.PanelRAOPlot,3,1);
            app.TiledLayoutRAO.TileSpacing = 'compact';
            xlabel(app.TiledLayoutRAO,'Period');
            ylabel(app.TiledLayoutRAO,'RAO');

            % Creates Axes in Tiled Layout
            app.RAOAxes = zeros(3,1);
            % RAOAxes set so that ids [1,2,3] correspond to plots [2,3,1]
            app.RAOAxes(3) = nexttile(app.TiledLayoutRAO,1); 
            app.RAOAxes(1) = nexttile(app.TiledLayoutRAO,2);
            app.RAOAxes(2) = nexttile(app.TiledLayoutRAO,3);
            updateRAOAxesTitles(app);
            linkaxes([app.RAOAxes(1),app.RAOAxes(2),app.RAOAxes(3)],'x');
        end
        
        function [] = log(app,text)
            app.ConsoleLog.Value = cellstr([app.ConsoleLog.Value(:)',text]);
            pause(0.00000001);
            uiprogressdlg(app.UIFigure);
            scroll(app.ConsoleLog, 'bottom');
        end

        function [] = updateGeometryPlot(app)
            % Only updates geometry when plot is open
            if (ishandle(app.GeometryFigureNumber))
                figure(app.GeometryFigureNumber);
                try
                    pdegplot(app.Model,"FaceLabels","on");
                catch error                
                    log(app,error.message);
                end
            end
        end

        function [] = updateMeshPlot(app)
            if app.ShowBodyCheckBox.Value == true
                mesh = app.MeshBodySurface;
            else
                mesh = app.MeshSubmergedSurface;
            end

            % Trimesh opens an empty figure before 'Parent' steals its
            %  draw focus. This opens the empty figure itself so it can be 
            %  automatically closed afterwards.
            figure(app.TrimeshScuffedFigureNumber);
            try
                trimesh(mesh,'Parent',app.MeshPlot);
            catch
                close(app.TrimeshScuffedFigureNumber);
                if app.ShowMassCheckBox.Value == true
                    try
                        updatePointMassesPlot(app);
                    catch
                    end
                end
                return;
            end
            close(app.TrimeshScuffedFigureNumber);

            hold(app.MeshPlot,"on");
            axis(app.MeshPlot,"equal");

            % Finds each triangle's center point and face normal vectors
            CP  = incenter(mesh);
            FN = -faceNormal(mesh);
            
            if app.NormalsCheckBox.Value == true
                % Plotes the triangulation object's normals
                quiver3(CP(:,1),CP(:,2),CP(:,3), ...
                     FN(:,1),FN(:,2),FN(:,3),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end

            if app.RotationNormalsCheckBox.Value == true
                % Creates each triangle's 6-dimensional normal vector
                FN6 = normal6DOF(CP,FN);
                
                % Plotes the triangulation object's rotational normals
                quiver3(app.MeshPlot,CP(:,1),CP(:,2),CP(:,3), ...
                     FN6(:,4),FN6(:,5),FN6(:,6),0.5,'color','r', ...
                     'Parent',app.MeshPlot);
            end

            hold(app.MeshPlot,"off");

            if app.ShowMassCheckBox.Value == true
                try
                    updatePointMassesPlot(app);
                catch
                end
            end
        end

        function [] = updatePointMassesPlot(app)
            [row,~] = size(app.PointMasses);
            hold(app.MeshPlot,"on");
            for j = 1:row
                plot3(app.MeshPlot,app.PointMasses(j,2), ...
                    app.PointMasses(j,3),app.PointMasses(j,4),"*");
            end
            hold(app.MeshPlot,"off");
        end

        function [] = updateRAOPlot(app)
            T = app.ResponsePeriods;

            plotShapes = ['o','+','*','x','s','d','v','^','<','>','p','h','.'];
            [~,nShapes] = size(plotShapes);
            plotColors = ['r','b','k','m','g','c'];
            [~,nColors] = size(plotColors);

            % Sets offset for RAO directions [1:3] vs [4:6]
            if app.HeaveSurgeSwayCheckBox.Value == true
                offset = 0;
            else
                offset = 3;
            end

            [~,nT] = size(T);
            [~,ntheta] = size(app.ResponseThetas);

            for k = (1 + offset):(3 + offset)
                % Picks plotid from index of [1:3] based on offset
                plotID = app.RAOAxes(k - offset);
                
                % Clears the plot bc clf opens a fig :/
                hold(plotID,"off");                                
                for i = 1:ntheta % first theta already plotted
                    % Uses a different shape and color for each angle
                    m = mod(i - 1,nShapes) + 1;
                    n = mod(i - 1,nColors) + 1;
                    plotType = append(plotColors(n),plotShapes(m));
                    % Sets plot legend name to its angle
                    name = sprintf("%.3fpi",app.ResponseThetas(i)/pi);
                    % Fills new variable with correct dim so scatter
                    %  doesn't freak out
                    R = zeros(1,nT);
                    R(1,:) = app.ResponseAOs(k,i,:);
                    % Plots the RAO of each angle. This is responsible for
                    %  the slowdown. Using plot will be faster but legend
                    %  will need to be set manually.
                    scatter(plotID,T.',R.',plotType,'DisplayName',name);
                    hold(plotID,"on");
                end
                hold(plotID,"off");

                xlim(plotID,[min(T) - 0.5,max(T) + .5]);
            end  

            l = legend(app.RAOAxes(k - offset));
            title(l,'Incident Angle');

            updateRAOAxesTitles(app);
        end
        
        function [] = updateRAOAxesTitles(app)
            if app.HeaveSurgeSwayCheckBox.Value == true
                title(app.RAOAxes(3),'Heave');
                title(app.RAOAxes(1),'Surge');
                title(app.RAOAxes(2),'Sway');
            else
                title(app.RAOAxes(3),'Yaw');
                title(app.RAOAxes(1),'Roll');
                title(app.RAOAxes(2),'Pitch');
            end
        end
        
        function [] = printRuntime(app,N,G,nT)
            try
                log(app,"Creating parallel pool.")
                p = gcp(); % If no pool, create new one.
            catch
            end

            if isempty(p)
                ps = 1;
            else
                ps = p.NumWorkers;
            end

            log(app,sprintf("Parallel pool created with %d workers.",ps))
            
            S6 = 4.75; % measured speedup with 6 cores
            fp = 6/5*(1-1/S6); % parallel fraction
            N6 = 203; % number of parallel runs for S6 test
            n = N/N6; % factor difference in parallel tasks
            fpn = n/(n + (1 - fp)/fp); % new parallel fraction
            S = 1/(fpn/ps+(1-fpn)); % final expected speedup
            
            % time for arbitrary green function
            t = timeit(@() greenFunction([0.1,0.1,0],[1,1,0],0.4));
            % 2*t runtime per greenAndPartial for G gaussian points for 
            %  (N-1)*N runs divided by a speedup S times nT periods
            runseconds = t*2*G*(N - 1)*N*nT/S;
            runminutes = runseconds/60;
           
            log(app,sprintf("Estimated Runtime ~ %.0fs or %.1fmin",runseconds,runminutes));
        end
        
        function importPointMasses(app,path,file)
            pointMasses = readmatrix(append(path,file));

            [~,col] = size(pointMasses);
            if col == 4
                app.PointMasses = pointMasses;
                % Reset offset values from last point masses
                app.PointMassesOffset = [0,0,0];
                app.PointMassesAngleOffset = [0,0,0];

                % Updates lamp if this was the last needed item
                if ~isempty(app.MeshSubmergedSurface) && ~isempty(app.PointMasses)
                    app.MeshLamp.Color = 'green';
                else % just in case it wasn't caught elsewhere
                    app.MeshLamp.Color = 'red';
                end
                updateMeshPlot(app);
            else
                error("Failed to import %s.\nIncorrect dimensions.");
            end
        end
        
        function importModel(app,path,file)
            model = createpde();
            importGeometry(model,append(path,file));
            scale(model.Geometry, app.ScaleField.Value);
            app.LastScaleValue = 1; % reset scale values for new object
            app.Model = model;
            % Resets all model derived values
            app.MeshBody = [];
            app.MeshBodySurface = [];
            app.MeshSubmergedSurface = [];
            app.MeshLamp.Color = 'red';
            % Store the objects path to be copied during a save
            app.ObjectPath = path;
            app.ObjectFile = file;

            % Opens new geometry window and updates plots
            figure(app.GeometryFigureNumber);
            updateGeometryPlot(app);
            updateMeshPlot(app);
        end
        
        function VE = getVolumeElements(app)
            [~,N] = size(app.Model.Mesh.Elements);

            VE = zeros(1,N); % List of volumes for each element
            for j = 1:N
                VE(j) = volume(app.Model.Mesh,j);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            initPanelRAO(app);
            app.GeometryFigureNumber = 1;
            app.TrimeshScuffedFigureNumber = 2;
            app.LastScaleValue = 1;
            axis(app.MeshPlot, "equal");
            app.Periods = str2num(app.PeriodListEditField.Value);
            app.YawRollPitchCheckBox.Value = ~app.HeaveSurgeSwayCheckBox.Value;
            app.ShowSurfaceCheckBox.Value = ~app.ShowBodyCheckBox.Value;
            app.Thetas = str2num(app.IncidentThetaListEditField.Value);
            uiprogressdlg(app.UIFigure);
            app.VelocityPotentials = [];
            app.VelocityPotentialPeriods = [];
            app.ResponseThetas = [];
            app.ResponsePeriods = [];
            app.PointMassesOffset = [];
            app.PointMassesAngleOffset = [];
            app.EP = 1E-6; 
            app.ConsoleLog.Value = "Welcome to AUV_FEA_Solver 1.4";
        end

        % Button pushed function: ImportPointMassesButton
        function ImportPointMassesButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.UIFigure); % requests focus back
            try
                log(app,append("Importing point masses: ",file));
                importPointMasses(app,path,file);
                log(app,"Point masses successfully imported.");
            catch error                
                log(app,error.message);
            end
        end

        % Value changed function: ShowMassCheckBox
        function ShowMassCheckBoxValueChanged(app, event)
            updateMeshPlot(app);
        end

        % Button pushed function: ImportModelButton
        function ImportModelButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            figure(app.UIFigure); % requests focus back
            try
                log(app,append("Importing model: ",file));
                importModel(app,path,file);
                log(app,"Model successfully imported.");
            catch error                
                log(app,error.message);
            end
        end

        % Value changed function: ScaleField
        function ScaleFieldValueChanged(app, event)
            if app.ScaleField.Value <= 0
                app.ScaleField.Value = app.LastScaleValue;
                log(app,"Cannot scale by zero or less.");
            else
                % Keeps the scale relative to when it was first imported
                value = app.ScaleField.Value/app.LastScaleValue;
                try
                    scale(app.Model.Geometry, value);
                    app.LastScaleValue = app.ScaleField.Value;
                    figure(app.GeometryFigureNumber);
                    updateGeometryPlot(app);
                catch
                end
            end
        end

        % Button pushed function: MeshButton
        function MeshButtonPushed(app, event)
            try
                log(app,sprintf("Meshing model: Hmin: %f, Hmax: %f",app.HminField.Value,app.HmaxField.Value));
                generateMesh(app.Model, 'GeometricOrder','linear','Hmin',app.HminField.Value,'Hmax',app.HmaxField.Value);
                
                % Makes a triangulation object of Model.Mesh's surface
                app.MeshBody = triangulation(app.Model.Mesh.Elements.',app.Model.Mesh.Nodes.');
                [T,P] = freeBoundary(app.MeshBody);
                [Ntot,~] = size(T);
                app.MeshBodySurface = triangulation(T,P);
                app.MeshSubmergedSurface = getSurfaceSubmerged(T,P,app.EP);
                [Nsub,~] = size(app.MeshSubmergedSurface.ConnectivityList);

                % Updates lamp if this was the last needed item
                if ~isempty(app.MeshSubmergedSurface) && ~isempty(app.PointMasses)
                    app.MeshLamp.Color = 'green';
                else % just in case it wasn't caught elsewhere
                    app.MeshLamp.Color = 'red';
                end

                updateMeshPlot(app);
                log(app,sprintf("Sucessfully meshed model.\nNtotal = %d, NSubmerged = %d",Ntot,Nsub));
            catch error             
                log(app,error.message);
            end
        end

        % Value changed function: NormalsCheckBox
        function NormalsCheckBoxValueChanged(app, event)
            updateMeshPlot(app);   
        end

        % Value changed function: RotationNormalsCheckBox
        function RotationNormalsCheckBoxValueChanged(app, event)
            updateMeshPlot(app);
        end

        % Button pushed function: SubmergeMeshButton
        function SubmergeMeshButtonPushed(app, event)
            try
                CL = app.MeshBody.ConnectivityList;
                P0 = app.MeshBody.Points; 
                VE = getVolumeElements(app);
            catch
                log(app,"Missing a meshed model.")
                return
            end

            p = app.WaterDensity.Value;

            try
            [M,CM] = massMoments(app.PointMasses);
            catch
                log(app,"Import point masses first.")
                return
            end

            % Maximum mass that can be offset
            MM = p*volume(app.Model.Mesh); 
            if M > MM
                log(app,"Too much mass/ Not enough volume to float.")
                return
            end

            log(app,"Finding neutral buoyancy postion...");
            translate(app.Model.Geometry,-CM);

            VT = M / p; % target volume
            
            pitch = 0; % records total pitch change
            E = -1; % current values increment by 10^E
            [PN,CBN] = moveDepth(CL,P0,VE,VT,app.EP); % floats object at current pitch
            dZ = PN(1,3) - P0(1,3); % records height difference
            CMN = CM + [0,0,dZ]; % records new CM position
            if app.SetPitchCheckBox.Value == true
                dXN = CBN(1) - CMN(1);

                log(app,sprintf("Current pitch accuracy: %.9f",10^E*pi/2))
                
                while abs(dXN) > app.EP
                    dPY = sign(dXN)*10^E*pi/2; % delta pitch
                    pitch = pitch + dPY; % total pitch
                    P = (PN - CMN)*roty(dPY) + CMN; % Center points on origin about CM and rotate
                    [PN,CBN] = moveDepth(CL,P,VE,VT,app.EP);  % Find correct depth V*p = M
                    dZ = PN(1,3) - P(1,3); % record change in depth
                    CMN = CMN + [0,0,dZ]; % move CM to correct dpeth
                    dX = dXN;
                    dXN = CBN(1) - CMN(1); 
                    if sign(dXN) ~= sign(dX)
                        E = E - 1;
                        log(app,sprintf("Current pitch accuracy: %.9f",10^E*pi/2))
                    end
                end
            end

            log(app,"Neutral buoyancy postion found.")
            log(app,"Now finishing up.")
            
            rotate(app.Model.Geometry,-pitch*180/pi,[0 0 0],[0 1 0]);
            translate(app.Model.Geometry,CMN);
            updateGeometryPlot(app)

            % Update point masses to new pos + angle
            [NPM,~] = size(app.PointMasses);
            for i = 1:NPM
                app.PointMasses(i,2:4) = (app.PointMasses(i,2:4) - CM)*roty(pitch) + CMN;
            end

            app.PointMassesOffset = app.PointMassesOffset + CM - CMN;
            app.PointMassesAngleOffset = app.PointMassesAngleOffset + [0,pitch,0];

            % Cut out surface mesh across z = 0
            app.MeshBody = triangulation(CL,PN);
            [CLN,PNN] = freeBoundary(app.MeshBody);
            app.MeshBodySurface = triangulation(CLN,PNN);
            app.MeshSubmergedSurface = getSurfaceSubmerged(CLN,PNN,app.EP);

            if ~isempty(app.MeshSubmergedSurface) && ~isempty(app.PointMasses)
                app.MeshLamp.Color = 'green';
            else
                app.MeshLamp.Color = 'red';
            end

            updateMeshPlot(app);
            log(app,"Done.")
        end

        % Value changed function: ShowSurfaceCheckBox
        function ShowSurfaceCheckBoxValueChanged(app, event)
            app.ShowBodyCheckBox.Value = ~app.ShowSurfaceCheckBox.Value;
            try
                updateMeshPlot(app);
            catch
            end
        end

        % Value changed function: ShowBodyCheckBox
        function ShowBodyCheckBoxValueChanged(app, event)
            app.ShowSurfaceCheckBox.Value = ~app.ShowBodyCheckBox.Value;
            try
                updateMeshPlot(app);
            catch
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isequal(app.MeshLamp.Color,[0,1,0]) % [r,g,b]
                path = uigetdir('.','Choose an empty folder'); 
                figure(app.UIFigure); % requests focus back
                
                path = append(path,'\');
                log(app,append("Attempting to save to: ",path))
                ObjectPathDest = append(app.ObjectPath,app.ObjectFile);
                pointMassesName = "Masses.txt";
                pointMassesDest = append(path,pointMassesName);
                surfaceName = "SurfaceMesh.stl";
                surfaceDest = append(path,surfaceName);
                saveName = 'save.txt';
                saveDest = append(path,saveName);

                try
                    if isfile(append(path,app.ObjectFile))
                        error(app.ObjectFile);
                    elseif isfile(pointMassesDest)
                        error(pointMassesName);
                    elseif isfile(surfaceDest)
                        error(surfaceName)
                    elseif isfile(saveDest)
                        error(saveName);
                    end
                catch filename
                    log(app,sprintf("File %s aready exists.",filename.message));
                    return
                end

                try
                    copyfile(ObjectPathDest,path);
                    writematrix(app.PointMasses,pointMassesDest);
                    stlwrite(app.MeshSubmergedSurface,surfaceDest);
                    fid = fopen(saveDest,'w');
                    fprintf(fid, 'Object File Name=%s\n',app.ObjectFile);
                    fprintf(fid, 'Object File Scale=%.10f\n',app.ScaleField.Value);
                    fprintf(fid, 'Mesh Hmax=%.10f\n',app.HmaxField.Value);
                    fprintf(fid, 'Mesh Hmin=%.10f\n',app.HminField.Value);
                    fprintf(fid, 'Submerged Surface File Name=%s\n',surfaceName);
                    fprintf(fid, 'Point Masses File Name=%s\n',pointMassesName);
                    fprintf(fid, 'Point Masses Offset=[%.20f,%.20f,%.20f]\n',app.PointMassesOffset);
                    fprintf(fid, 'Point Masses Angular Offset=[%.20f,%.20f,%.20f]\n',app.PointMassesAngleOffset);
                    fprintf(fid, 'Water Density=%.10f\n',app.WaterDensity.Value);
                    fclose(fid);
                catch err
                    try
                        fclose(fid);
                    catch
                    end
                    log(app,err.message);
                    return
                end
                log(app,"Saved successful.");
            else
                log(app,"Can't save while the light is red. Make a submerged mesh first!\n");
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.txt','Chose a save file to import');
            figure(app.UIFigure); % requests focus back
 
            try
                saveDest = append(path,file);
            catch
            end
            
            if ~isfile(saveDest)
                 log(app,sprintf("File %s doesn't exist.",saveName))
                 return
            end

            try
                filetext = fileread(saveDest);
                matches = regexp(filetext,'[^\r\n]*=[^\r\n]*','match');
                results = strings(1,9);
                c = 1;
                for match = matches
                    split = strsplit(match{1},'=');
                    results(c) = split{2};
                    c = c + 1;
                end

                objectFileName = results(1);
                objectScaleStr = results(2);
                hmaxStr = results(3);
                hminStr = results(4);
                surfaceName = results(5);
                pointMassesName = results(6);
                offsetStr = results(7);
                angleOffsetStr = results(8);
                app.WaterDensity.Value = str2double(results(9));

                log(app,"Importing masses...")
                importPointMasses(app,path,pointMassesName);
                [~,CM] = massMoments(app.PointMasses);

                log(app,"Importing model...")
                app.ScaleField.Value = str2double(objectScaleStr);
                importModel(app,path,objectFileName);

                log(app,"Updating model...")
                offset = str2num(offsetStr);
                app.PointMassesOffset = offset;
                angleOffset = str2num(angleOffsetStr);
                app.PointMassesAngleOffset = angleOffset;
                translate(app.Model.Geometry,-(CM + offset));
                rotate(app.Model.Geometry,-angleOffset(2)*180/pi,[0 0 0],[0 1 0]);
                translate(app.Model.Geometry,CM);
                updateGeometryPlot(app);

                log(app,"Meshing model...")
                app.HmaxField.Value = str2double(hmaxStr);
                app.HminField.Value = str2double(hminStr);
                generateMesh(app.Model, 'GeometricOrder','linear','Hmin',app.HminField.Value,'Hmax',app.HmaxField.Value);
                % Makes a triangulation object of Model.Mesh's surface
                app.MeshBody = triangulation(app.Model.Mesh.Elements.',app.Model.Mesh.Nodes.');
                [T,P] = freeBoundary(app.MeshBody);
                app.MeshBodySurface = triangulation(T,P);
                updateMeshPlot(app);

                log(app,"Importing surface mesh...")
                app.MeshSubmergedSurface = stlread(append(path,surfaceName));
                [Nsub,~] = size(app.MeshSubmergedSurface.ConnectivityList);
                updateMeshPlot(app);

                app.MeshLamp.Color = 'green';
                log(app,sprintf("Sucessfully loaded file with NSubmerged = %d",Nsub));
            catch error             
                log(app,error.message);
            end
        end

        % Button pushed function: ClearPhisButton
        function ClearPhisButtonPushed(app, event)
            answer = questdlg('Clicking ''Continue'' will remove all calculated responses and velocity potentials. Please export any data that you wish to keep. So, are you sure you want to continue?', ...
                'Clear Results', ...
                'Continue','Abort','Abort');
            switch answer
                case 'Continue'
                    app.VelocityPotentials = [];
                    app.VelocityPotentialPeriods = [];
                    app.Responses = [];
                    app.ResponseAOs = [];
                    app.ResponseThetas = [];
                    app.PhisLamp.Color = 'red';
                    log(app,"Content successfully deteled.");
                case 'Abort'
                    log(app,"Deletion aborted.");
                otherwise % Says nothing on using "X" to exit
                    figure(app.UIFigure); % requests focus back
            end
        end

        % Button pushed function: ImportPhisButton
        function ImportPhisButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                vpsm = readmatrix(append(path,file)); % imports velocity potentials
                
                N = vpsm(1,1); % first value list the length of velocity potentials
                nT = vpsm(1,2); % first value list the length of periods 

                velocityPotentials = zeros(6,N,nT);
                for j = 1:nT
                    for i = 1:6
                        velocityPotentials(i,:,j) = vpsm(6*j + i - 5,1:N);
                    end
                end

                app.VelocityPotentials = velocityPotentials;
                app.VelocityPotentialPeriods = vpsm(1,3:nT + 2);

                if size(app.VelocityPotentialPeriods) > 0
                    app.PhisLamp.Color = 'green';
                end

                log(app,append("Imported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Value changed function: PeriodListEditField
        function PeriodListEditFieldValueChanged(app, event)
            try
                app.Periods = str2num(app.PeriodListEditField.Value);
            catch error                
                log(app,error.message);
            end
        end

        % Button pushed function: CalculatePhisButton
        function CalculatePhisButtonPushed(app, event)
            T = setdiff(app.Periods,app.VelocityPotentialPeriods);
            g = app.GravityEditField.Value;
            mesh = app.MeshSubmergedSurface;
            
            try
                % Finds each triangle's center point and face normal vectors
                CP  = incenter(mesh);
                FN = -faceNormal(mesh);
            catch
                log(app,"Add a mesh first.");
                return
            end
            
            [N,~] = size(CP);

            % Defines a list of N triangles so that parfor can nicely slice data
            Tri = zeros(3,3,N);
            for j = 1:N
                Tri(:,:,j) = mesh.Points(mesh.ConnectivityList(j,:),:);
            end

            [~,nT] = size(T);

            G = 3; % number of gaussian points in case of future variable
            printRuntime(app,N,G,nT);

            % Calculates veloctiy potentials for each period 
            phis = zeros(6,N,nT);
            for j = 1:nT
                log(app,sprintf("----%.0f%%----",(j - 1)/nT*100));
                
                K = (2*pi()/T(j))^2/g; % K = w^2/g

                phis(:,:,j) = velocityPotential(Tri,CP,FN,K);
            end
            log(app,"----Done----");

            % Combine all periods
            [~,nTOld] = size(app.VelocityPotentialPeriods);
            app.VelocityPotentialPeriods = [app.VelocityPotentialPeriods,T];

            % Combine all velocity potentials
            phisOld = app.VelocityPotentials;
            app.VelocityPotentials = zeros(6,N,nT+nTOld);
            app.VelocityPotentials(:,:,1:nTOld) = phisOld;
            app.VelocityPotentials(:,:,(nTOld + 1):(nTOld + nT)) = phis;
        end

        % Button pushed function: ExportPhisButton
        function ExportPhisButtonPushed(app, event)
            [file,path] = uiputfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                [~,N,nT] = size(app.VelocityPotentials);

                % Writes VelocityPotentials to the correct form for export
                export = zeros(nT*6 + 1,max(N,nT + 1));
                export(1,1) = N;
                export(1,2) = nT;
                export(1,3:nT + 2) = app.VelocityPotentialPeriods;
                for j = 1:nT
                    for i = 1:6
                        export(1 + (j - 1)*6 + i,1:N) = app.VelocityPotentials(i,:,j);
                    end
                end

                 writematrix(export, append(path,file));

                log(app,append("Exported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Value changed function: IncidentThetaListEditField
        function IncidentThetaListEditFieldValueChanged(app, event)
            try
                app.Thetas = str2num(app.IncidentThetaListEditField.Value);
            catch error                
                log(app,error.message);
            end
        end

        % Button pushed function: CalculateRAOsButton
        function CalculateRAOsButtonPushed(app, event)
            PM = app.PointMasses;
            g = app.GravityEditField.Value;
            p = app.WaterDensity.Value;
            T = app.VelocityPotentialPeriods;
            phis = app.VelocityPotentials;
            theta = app.Thetas;
            mesh = app.MeshSubmergedSurface;

            try
                M = bodyInertiaMatrix(PM);
            catch
                log(app,"Must import point masses first!");
                return
            end
            
            try
                CL = app.MeshBody.ConnectivityList;
                P = app.MeshBody.Points; 
                VE = getVolumeElements(app);
                [VS,CB] = volumeMomentsSubmerged(CL,P,VE);
                C = hydrostaticRestoringMatrix(PM,g,p,VS,CB,mesh);
            catch error
                log(app,error.message);
                return
            end

            [N,~] = size(mesh.ConnectivityList);
            CP = incenter(mesh);
            FN = -faceNormal(mesh);
            FN6 = normal6DOF(CP,FN);

            % Defines a list of N triangles so that parfor can nicely slice data
            Tri = zeros(3,3,N);
            for j = 1:N
                Tri(:,:,j) = mesh.Points(mesh.ConnectivityList(j,:),:);
            end

            [~,nT] = size(T);
            [~,ntheta] = size(theta);

            % Responses and response amplitude operators
            Hs = zeros(6,ntheta,nT);
            RAOs = zeros(6,ntheta,nT);

            % Calculates the responses and response amplitude operators
            for j = 1:nT
                log(app,sprintf("----%.0f%%----",(j - 1)/nT*100));

                w = 2*pi()/T(j);
                K = w^2/g;
                [D] = addedMassAndDampingMatrices(Tri,phis(:,:,j),FN6,p);

                for i = 1:ntheta
                    [F] = excitingForce(Tri,phis(:,:,j),FN6,p,K,g,theta(i));
                
                    nu = linsolve(-w^2*(M + D) + C,F);
                
                    Hs(:,i,j) = nu; % response function
                    RAOs(:,i,j) = abs(Hs(:,i,j)); % response amplitude operator
                end
            end
            
            % Save all values
            app.ResponsePeriods = T;
            app.ResponseThetas = theta;
            app.Responses = Hs;
            app.ResponseAOs = RAOs;

            try
                updateRAOPlot(app);
                log(app,"----Done----");
            catch
                log(app,"Calculate velocity potentials first!");
            end
        end

        % Button pushed function: ExportResponseButton
        function ExportResponseButtonPushed(app, event)
            [file,path] = uiputfile('*.txt');
            figure(app.UIFigure); % requests focus back

            try
                [~,ntheta,nT] = size(app.ResponseAOs);

                % Writes Responses to the correct form for export
                export = zeros(ntheta*6 + 2,max(ntheta + 1,nT + 1));
                export(1,1) = ntheta;
                export(1,2:ntheta + 1) = app.ResponseThetas;
                export(2,1) = nT;
                export(2,2:nT + 1) = app.VelocityPotentialPeriods;
                for j = 1:ntheta
                    for i = 1:6
                        export(2 + (j - 1)*6 + i,1:nT) = app.Responses(i,j,:);
                    end
                end

                 writematrix(export, append(path,file));

                log(app,append("Exported: ",file));
            catch error
                log(app,error.message);
            end
        end

        % Value changed function: HeaveSurgeSwayCheckBox
        function HeaveSurgeSwayCheckBoxValueChanged(app, event)
            app.YawRollPitchCheckBox.Value = ~app.HeaveSurgeSwayCheckBox.Value;
            try
                updateRAOPlot(app);
            catch
            end
        end

        % Value changed function: YawRollPitchCheckBox
        function YawRollPitchCheckBoxValueChanged(app, event)
            app.HeaveSurgeSwayCheckBox.Value = ~app.YawRollPitchCheckBox.Value;
            try
                updateRAOPlot(app);
            catch
            end
        end

        % Size changed function: PanelRAO
        function PanelRAOSizeChanged(app, event)
            position = app.PanelRAO.Position;
            if isempty(app.ConsoleHeight) 
                app.ConsoleHeight = app.PanelConsole.Position(4);
            end
            app.PanelConsole.Position(3) = position(3);
            app.PanelRAOPlot.Position(2) = app.ConsoleHeight;
            app.PanelRAOPlot.Position(3) = position(3);
            app.PanelRAOPlot.Position(4) = position(4) - app.ConsoleHeight;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelRAO = uipanel(app.UIFigure);
            app.PanelRAO.SizeChangedFcn = createCallbackFcn(app, @PanelRAOSizeChanged, true);
            app.PanelRAOPlot = uipanel(app.PanelRAO);
            app.PanelConsole = uipanel(app.PanelRAO);
            app.ConsoleLog = uitextarea(app.PanelConsole);
            app.ConsoleLog.Editable = 'off';
            app.PanelMesh = uipanel(app.UIFigure);
            app.MeshPlot = uiaxes(app.PanelMesh);
            title(app.MeshPlot, 'Mesh Plot')
            xlabel(app.MeshPlot, 'X')
            ylabel(app.MeshPlot, 'Y')
            zlabel(app.MeshPlot, 'Z')
            app.MeshPlot.XLim = [-1 0.5];
            app.MeshPlot.YLim = [-0.5 0.5];
            app.MeshPlot.ZLim = [-0.5 0];
            app.LeftOptions = uipanel(app.PanelMesh);
            app.LeftOptions.Title = 'Mesh Options';
            app.ImportModelButton = uibutton(app.LeftOptions, 'push');
            app.ImportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelButtonPushed, true);
            app.ImportModelButton.Text = 'Import Model';
            app.MeshButton = uibutton(app.LeftOptions, 'push');
            app.MeshButton.ButtonPushedFcn = createCallbackFcn(app, @MeshButtonPushed, true);
            app.MeshButton.Text = 'Mesh';
            app.NormalsCheckBox = uicheckbox(app.LeftOptions);
            app.NormalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalsCheckBoxValueChanged, true);
            app.NormalsCheckBox.Text = 'Normals';
            app.RotationNormalsCheckBox = uicheckbox(app.LeftOptions);
            app.RotationNormalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotationNormalsCheckBoxValueChanged, true);
            app.RotationNormalsCheckBox.Text = 'Rot. Norm';
            app.ScaleLabel = uilabel(app.LeftOptions);
            app.ScaleLabel.Text = 'Scale';
            app.ScaleField = uieditfield(app.LeftOptions, 'numeric');
            app.ScaleField.ValueChangedFcn = createCallbackFcn(app, @ScaleFieldValueChanged, true);
            app.ScaleField.Value = 1;
            app.HminHmaxLabel_3 = uilabel(app.LeftOptions);
            app.HminHmaxLabel_3.Text = 'Hmax';
            app.HmaxField = uieditfield(app.LeftOptions, 'numeric');
            app.HmaxField.Value = 0.05;
            app.HminHmaxLabel = uilabel(app.LeftOptions);
            app.HminHmaxLabel.Text = 'Hmin';
            app.HminField = uieditfield(app.LeftOptions, 'numeric');
            app.HminField.Value = 0.01;
            app.ImportPointMassesButton = uibutton(app.LeftOptions, 'push');
            app.ImportPointMassesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPointMassesButtonPushed, true);
            app.ImportPointMassesButton.Text = 'Import Point Masses';
            app.ShowMassCheckBox = uicheckbox(app.LeftOptions);
            app.ShowMassCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMassCheckBoxValueChanged, true);
            app.ShowMassCheckBox.Text = 'Show Mass';
            app.ShowMassCheckBox.Value = true;
            app.ScaleLabel_4 = uilabel(app.LeftOptions);
            app.ScaleLabel_4.Text = 'Water Density';
            app.WaterDensity = uieditfield(app.LeftOptions, 'numeric');
            app.WaterDensity.Value = 1026;
            app.SubmergeMeshButton = uibutton(app.LeftOptions, 'push');
            app.SubmergeMeshButton.ButtonPushedFcn = createCallbackFcn(app, @SubmergeMeshButtonPushed, true);
            app.SubmergeMeshButton.Text = 'Submerge Mesh';
            app.LoadButton = uibutton(app.LeftOptions, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SetPitchCheckBox = uicheckbox(app.LeftOptions);
            app.SetPitchCheckBox.Text = 'Set Pitch';
            app.SetPitchCheckBox.Value = true;
            app.SetRollCheckBox = uicheckbox(app.LeftOptions);
            app.SetRollCheckBox.Enable = 'off';
            app.SetRollCheckBox.Text = 'Set Roll';
            app.SaveButton = uibutton(app.LeftOptions, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ShowBodyCheckBox = uicheckbox(app.LeftOptions);
            app.ShowBodyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowBodyCheckBoxValueChanged, true);
            app.ShowBodyCheckBox.Text = 'Show Body';
            app.ShowBodyCheckBox.Value = true;
            app.ShowSurfaceCheckBox = uicheckbox(app.LeftOptions);
            app.ShowSurfaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSurfaceCheckBoxValueChanged, true);
            app.ShowSurfaceCheckBox.Text = 'Show Surf.';
            app.MeshLamp = uilamp(app.LeftOptions);
            app.RightOptions = uipanel(app.PanelMesh);
            app.RightOptions.Title = 'RAO Options';
            app.ScaleLabel_3 = uilabel(app.RightOptions);
            app.ScaleLabel_3.Text = 'Gravity';
            app.GravityEditField = uieditfield(app.RightOptions, 'numeric');
            app.GravityEditField.Value = 9.81;
            app.ImportPhisButton = uibutton(app.RightOptions, 'push');
            app.ImportPhisButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPhisButtonPushed, true);
            app.ImportPhisButton.Text = 'Import Φs';
            app.PeriodListEditFieldLabel = uilabel(app.RightOptions);
            app.PeriodListEditFieldLabel.Text = 'Period List';
            app.PeriodListEditField = uieditfield(app.RightOptions, 'text');
            app.PeriodListEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodListEditFieldValueChanged, true);
            app.PeriodListEditField.Value = '[2:10]';
            app.IncidentListLabel = uilabel(app.RightOptions);
            app.IncidentListLabel.Text = 'Incident θ List';
            app.IncidentThetaListEditField = uieditfield(app.RightOptions, 'text');
            app.IncidentThetaListEditField.ValueChangedFcn = createCallbackFcn(app, @IncidentThetaListEditFieldValueChanged, true);
            app.IncidentThetaListEditField.Value = '[0,pi/2]';
            app.PhisLamp = uilamp(app.RightOptions);
            app.PhisAreLoadedLabel = uilabel(app.RightOptions);
            app.PhisAreLoadedLabel.Text = 'Φs Are Loaded';
            app.ClearPhisButton = uibutton(app.RightOptions, 'push');
            app.ClearPhisButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPhisButtonPushed, true);
            app.ClearPhisButton.Text = 'Clear Φs';
            app.CalculatePhisButton = uibutton(app.RightOptions, 'push');
            app.CalculatePhisButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePhisButtonPushed, true);
            app.CalculatePhisButton.Text = 'Calculate Φs';
            app.CalculateRAOsButton = uibutton(app.RightOptions, 'push');
            app.CalculateRAOsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRAOsButtonPushed, true);
            app.CalculateRAOsButton.Text = 'Calc. RAOs';
            app.ExportPhisButton = uibutton(app.RightOptions, 'push');
            app.ExportPhisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPhisButtonPushed, true);
            app.ExportPhisButton.Text = 'Export Φs';
            app.ExportResponseButton = uibutton(app.RightOptions, 'push');
            app.ExportResponseButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResponseButtonPushed, true);
            app.ExportResponseButton.Text = 'Export Resp.';
            app.HeaveSurgeSwayCheckBox = uicheckbox(app.RightOptions);
            app.HeaveSurgeSwayCheckBox.ValueChangedFcn = createCallbackFcn(app, @HeaveSurgeSwayCheckBoxValueChanged, true);
            app.HeaveSurgeSwayCheckBox.Text = 'Heave, Surge, Sway';
            app.HeaveSurgeSwayCheckBox.Value = true;
            app.OrLabel = uilabel(app.RightOptions);
            app.OrLabel.Text = 'Or';
            app.YawRollPitchCheckBox = uicheckbox(app.RightOptions);
            app.YawRollPitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @YawRollPitchCheckBoxValueChanged, true);
            app.YawRollPitchCheckBox.Text = 'Yaw, Roll, Pitch';
            app.DisplayLabel = uilabel(app.RightOptions);
            app.DisplayLabel.Text = 'Display';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AUV_FEA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end